classdef FlightLogAnalysisApplication < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        ControlPanel          matlab.ui.container.Panel
        GridLayout3           matlab.ui.container.GridLayout
        ShowHidePannerButton  matlab.ui.control.Button
        SaveFigurepngButton   matlab.ui.control.Button
        SaveFigurefigButton   matlab.ui.control.Button
        NewFigureButton       matlab.ui.control.Button
        NewAxesScatterButton  matlab.ui.control.Button
        NewAxesLineButton     matlab.ui.control.Button
        EditAxesButton        matlab.ui.control.Button
        DeleteSelectedButton  matlab.ui.control.Button
        EntityTreePanel       matlab.ui.container.Panel
        Tree                  matlab.ui.container.CheckBoxTree
        DataPanel             matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        Label                 matlab.ui.control.Label
        LoadDataButton        matlab.ui.control.Button
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = public)
        DataManager
        PlotManager
        SelectedNode
        CurrentData
        Figures = []
        NextFigureId = 1
        NextAxesId = 1
        PannerVisible logical = true
        TabGroup

    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [file, path] = uigetfile('*.csv', 'Select Flight Log Data');
            if isequal(file, 0)
                return;
            end
            filename = fullfile(path, file);
            try
                addpath(fullfile(fileparts(mfilename('fullpath')), 'src'));
                dataManager = FlightDataManager(); 
                success = dataManager.loadData(filename);
                if success
                    app.Label.Text = sprintf('Loaded: %s', file);
                    dataManager.calculateAll();
                    app.CurrentData = dataManager;
                    app.DataManager = dataManager;
                    app.Label.Text = [app.Label.Text, ' - Ready to create figures'];
                    % Update panner
                    for i = 1:length(app.Figures)
                        if isfield(app.Figures(i), 'Panner')
                            SupportFunctions.updatePannerData(app, i);
                        end
                    end
                else
                    app.Label.Text = 'Error loading file';
                end
            catch ME
                app.Label.Text = sprintf('Error: %s', ME.message);
            end
        end

        % Button pushed function: DeleteSelectedButton
        function DeleteSelectedButtonPushed(app, event)
            checkedNodes = app.Tree.CheckedNodes;
            if isempty(checkedNodes)
                uialert(app.UIFigure, 'Please check items in the tree to delete.', 'No Selection');
                return;
            end
            %Delete from last to first to avoid index issues
            for i = length(checkedNodes):-1:1
                node = checkedNodes(i);
                nodeText = node.Text;
                
                if startsWith(nodeText, 'Figure ')
                    figId = str2double(extractAfter(nodeText, 'Figure '));
                    SupportFunctions.deleteFigure(app, figId);
                elseif startsWith(nodeText, 'Axes ')
                    axesId = str2double(regexp(nodeText, '\d+', 'match', 'once'));
                    if ~isempty(node.Parent) && startsWith(node.Parent.Text, 'Figure ')
                        figId = str2double(extractAfter(node.Parent.Text, 'Figure '));
                        SupportFunctions.deleteAxes(app, figId, axesId);
                    end
                end
            end
            app.Tree.CheckedNodes = [];
        end

        % Button pushed function: EditAxesButton
        function EditAxesButtonPushed(app, event)
            [figId, axesId] = SupportFunctions.getSelectedIds(app);
            if isempty(axesId) || isempty(app.CurrentData), return; 
            end
            figureIndex = SupportFunctions.findFigureIndex(app, figId);
            axFieldName = sprintf('axes%d', axesId);
            axesInfo = app.Figures(figureIndex).Axes.(axFieldName);
            
            % Get available variables from raw and calculated data
            availableVars = app.CurrentData.RawData.Properties.VariableNames;
            if ~isempty(app.CurrentData.CalculatedData)
                availableVars = [availableVars, fieldnames(app.CurrentData.CalculatedData)'];
            end
            
            if strcmp(axesInfo.Type, 'scatter')
                % For scatter plots you select X variable first then Y variables
                [xIdx, tf] = listdlg('ListString', availableVars, 'SelectionMode', 'single', 'Name', 'Select X Variable');
                if ~tf, return; end
                
                xVar = availableVars{xIdx};
                [yIdx, tf] = listdlg('ListString', availableVars, 'SelectionMode', 'multiple', 'Name', 'Select Y Variables');
                if ~tf, return; end
                
                axesInfo.Variables = [{xVar}, availableVars(yIdx)];
                axesInfo.XVariable = xVar;
            else
                % For line plots you select only Y variable
                [idx, tf] = listdlg('ListString', availableVars, 'SelectionMode', 'multiple', 'Name', 'Select Variables');
                if ~tf, return; end
                
                axesInfo.Variables = availableVars(idx);
            end
            app.Figures(figureIndex).Axes.(axFieldName) = axesInfo;
            SupportFunctions.updateAxesPlot(app, figureIndex, axesId);
        end

        % Button pushed function: NewAxesLineButton
        function NewAxesLineButtonPushed(app, event)
            [figId, ~] = SupportFunctions.getSelectedIds(app);
            if isempty(figId)
                uialert(app.UIFigure, 'Please select a FIGURE in the tree first.', 'No Figure Selected');
                return;
            end
            SupportFunctions.addNewAxes(app, figId, 'line');
            app.Label.Text = sprintf('Line axes added to Figure %d', figId);
        end

        % Button pushed function: NewAxesScatterButton
        function NewAxesScatterButtonPushed(app, event)
            [figId, ~] = SupportFunctions.getSelectedIds(app);
            if isempty(figId)
                uialert(app.UIFigure, 'Please select a FIGURE in the tree first.', 'No Figure Selected');
                return;
            end
            SupportFunctions.addNewAxes(app, figId, 'scatter');
            app.Label.Text = sprintf('Scatter axes added to Figure %d', figId);
        end

        % Button pushed function: NewFigureButton
        function NewFigureButtonPushed(app, event)
            try
                SupportFunctions.createNewFigure(app);
            catch ME
                app.Label.Text = sprintf('Error creating figure: %s', ME.message);
            end
        end

        % Button pushed function: SaveFigurefigButton
        function SaveFigurefigButtonPushed(app, event)
            for i = 1:length(app.Tree.CheckedNodes)
                nodeText = app.Tree.CheckedNodes(i).Text;
                if startsWith(nodeText, 'Figure ')
                    figId = str2double(extractAfter(nodeText, 'Figure '));
                    [file, path] = uiputfile('*.fig', 'Save Figure as FIG', sprintf('figure_%d.fig', figId));
                    if ~isequal(file, 0)
                        figIndex = SupportFunctions.findFigureIndex(app, figId);
                        tempFig = figure('Visible', 'off');
                        copyobj(app.Figures(figIndex).GridLayout, tempFig);
                        saveas(tempFig, fullfile(path, file), 'fig');
                        close(tempFig);
                    end
                end
            end
        end

        % Button pushed function: SaveFigurepngButton
        function SaveFigurepngButtonPushed(app, event)
            for i = 1:length(app.Tree.CheckedNodes)
                nodeText = app.Tree.CheckedNodes(i).Text;
                if startsWith(nodeText, 'Figure ')
                    figId = str2double(extractAfter(nodeText, 'Figure '));
                    [file, path] = uiputfile('*.png', 'Save Figure as PNG', sprintf('figure_%d.png', figId));
                    if ~isequal(file, 0)
                        figIndex = SupportFunctions.findFigureIndex(app, figId);
                        app.TabGroup.SelectedTab = app.Figures(figIndex).Tab;
                        drawnow;

                        %Capture the screen and crop to tab group
                        frame = getframe(app.UIFigure);
                        im = frame2im(frame);
                        tabGroupPos = getpixelposition(app.TabGroup);
                        x = tabGroupPos(1);
                        y = tabGroupPos(2);
                        width = tabGroupPos(3);
                        height = tabGroupPos(4);
                        croppedIm = im(y:y+height-1, x:x+width-1, :);
                        imwrite(croppedIm, fullfile(path, file));
                    end
                end
            end
        end

        % Button pushed function: ShowHidePannerButton
        function ShowHidePannerButtonPushed(app, event)
            app.PannerVisible = ~app.PannerVisible;
            for i = 1:length(app.Figures)
                if isfield(app.Figures(i), 'Panner')
                    app.Figures(i).Panner.Visible = app.PannerVisible;
                    children = allchild(app.Figures(i).Panner);
                    for j = 1:length(children)
                        children(j).Visible = app.PannerVisible;
                    end
                end
            end
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes;
            if ~isempty(checkedNodes)
                clickedNode = checkedNodes(end);
                app.Tree.SelectedNodes = clickedNode;
                app.SelectedNode = clickedNode;
                nodeText = clickedNode.Text;
                
                if startsWith(nodeText, 'Figure')
                    app.Label.Text = sprintf('Selected: %s - Use "New Axes" buttons', nodeText);
                elseif startsWith(nodeText, 'Axes')
                    parentText = clickedNode.Parent.Text;
                    app.Label.Text = sprintf('Selected: %s in %s - Use "Edit Axes"', nodeText, parentText);
                else
                    app.Label.Text = sprintf('Selected: %s', nodeText);
                end
            else
                app.Label.Text = 'No selection - Click checkbox to select';
                app.SelectedNode = [];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.DataPanel = uipanel(app.GridLayout);
            app.DataPanel.Title = 'Data';
            app.GridLayout2 = uigridlayout(app.DataPanel);
            app.LoadDataButton = uibutton(app.GridLayout2, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = ' ';
            app.EntityTreePanel = uipanel(app.GridLayout);
            app.EntityTreePanel.Title = 'Entity Tree';
            app.Tree = uitree(app.EntityTreePanel, 'checkbox');
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.ControlPanel = uipanel(app.GridLayout);
            app.ControlPanel.Title = 'Control Panel';
            app.GridLayout3 = uigridlayout(app.ControlPanel);
            app.DeleteSelectedButton = uibutton(app.GridLayout3, 'push');
            app.DeleteSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedButtonPushed, true);
            app.DeleteSelectedButton.Text = 'Delete Selected';
            app.EditAxesButton = uibutton(app.GridLayout3, 'push');
            app.EditAxesButton.ButtonPushedFcn = createCallbackFcn(app, @EditAxesButtonPushed, true);
            app.EditAxesButton.Text = 'Edit Axes';
            app.NewAxesLineButton = uibutton(app.GridLayout3, 'push');
            app.NewAxesLineButton.ButtonPushedFcn = createCallbackFcn(app, @NewAxesLineButtonPushed, true);
            app.NewAxesLineButton.Text = 'New Axes (Line)';
            app.NewAxesScatterButton = uibutton(app.GridLayout3, 'push');
            app.NewAxesScatterButton.ButtonPushedFcn = createCallbackFcn(app, @NewAxesScatterButtonPushed, true);
            app.NewAxesScatterButton.Text = 'New Axes (Scatter)';
            app.NewFigureButton = uibutton(app.GridLayout3, 'push');
            app.NewFigureButton.ButtonPushedFcn = createCallbackFcn(app, @NewFigureButtonPushed, true);
            app.NewFigureButton.Text = 'New Figure';
            app.SaveFigurefigButton = uibutton(app.GridLayout3, 'push');
            app.SaveFigurefigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigurefigButtonPushed, true);
            app.SaveFigurefigButton.Text = 'Save Figure (.fig)';
            app.SaveFigurepngButton = uibutton(app.GridLayout3, 'push');
            app.SaveFigurepngButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigurepngButtonPushed, true);
            app.SaveFigurepngButton.Text = 'Save Figure (.png)';
            app.ShowHidePannerButton = uibutton(app.GridLayout3, 'push');
            app.ShowHidePannerButton.ButtonPushedFcn = createCallbackFcn(app, @ShowHidePannerButtonPushed, true);
            app.ShowHidePannerButton.Text = 'Show / Hide Panner';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FlightLogAnalysisApplication

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end